classdef AMainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GripforceButton              matlab.ui.control.Button
        TorqueStabilisationButton    matlab.ui.control.Button
        button3                      matlab.ui.control.Button
        WelcometotheexperimentLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        PleaseinputyournameTextAreaLabel  matlab.ui.control.Label
        UsernameTextArea             matlab.ui.control.TextArea
        GripTargetTrackingButton     matlab.ui.control.Button
        HI5TargetTrackingLabel       matlab.ui.control.Label
        button1                      matlab.ui.control.Button
        button2                      matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        PositionTrackingButton       matlab.ui.control.Button
        GripCalibrationButton        matlab.ui.control.Button
        count1                       matlab.ui.control.Label
        count2                       matlab.ui.control.Label
        count3                       matlab.ui.control.Label
        RunRDAUDPRobotExpScriptChecktrialnumbersLabel  matlab.ui.control.Label
        RunGripPythonLabel           matlab.ui.control.Label
        Label1ConfirmButton          matlab.ui.control.Button
        Label2ConfirmButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            numeric_array = [1 2 3];
            for i = 1:1:3
            index = randi(length(numeric_array));
            num = numeric_array(index);
            numeric_array(index) = [];
            name=strcat('button', num2str(i));
            app.(name).Text=num2str(num);
            app.(name).Visible=true;
            end
            app.button1.Visible = false;
            app.button2.Visible = false;
            app.button3.Visible = false;
            app.TorqueStabilisationButton.Visible = false;
            app.PositionTrackingButton.Visible = false;
            app.GripCalibrationButton.Visible = false;
            app.GripTargetTrackingButton.Visible = false;
            app.GripforceButton.Visible =false;
            
            
        end

        % Button pushed function: button3
        function button3Pushed(app, event)
            Username = char(app.UsernameTextArea.Value);
            save('username','Username');
            App_HI5_TargetTracking_ZeroAssisted
        end

        % Button pushed function: GripforceButton
        function GripforceButtonPushed(app, event)
            Username = char(app.UsernameTextArea.Value);
            save('username','Username');            
            App_grip
        end

        % Button pushed function: TorqueStabilisationButton
        function TorqueStabilisationButtonPushed(app, event)
            Username = char(app.UsernameTextArea.Value);
            save('username','Username');
            App_HI5_torque
        end

        % Button pushed function: GripTargetTrackingButton
        function GripTargetTrackingButtonPushed(app, event)
            Username = char(app.UsernameTextArea.Value);
            save('username','Username');            
            App_grip_tracking            
        end

        % Button pushed function: button1
        function button1Pushed(app, event)
            Username = char(app.UsernameTextArea.Value);
            save('username','Username');            
            App_HI5_TargetTracking_FullAssisted     
        end

        % Button pushed function: button2
        function button2Pushed(app, event)
            Username = char(app.UsernameTextArea.Value);
            save('username','Username');            
            App_HI5_TargetTracking_SemiAssisted           
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
             clear u1 %reading in App from script
             clear u2 %writing in script
             clear u3 % writing in app
             clear u4 %reading in script from app
             u3 = udpport('LocalPort', 2000);% udp for writing in app to send data to script
             %variables
             Username = char(app.UsernameTextArea.Value);
             save('username','Username'); 
             experiment = 9;%case 9 is save
             %need to initialize and send the below variables in order to
             %meet the required data format
             %sending info to the script
             write(u3, strcat(uniform_data_trials(experiment),uniform_data_trials(0),uniform_data_trials(0),uniform_data_trials(0)), 'string','LocalHost', 1000);
        end

        % Button pushed function: PositionTrackingButton
        function PositionTrackingButtonPushed(app, event)
            Username = char(app.UsernameTextArea.Value);
            save('username','Username');            
            App_HI5_position_track
        end

        % Button pushed function: GripCalibrationButton
        function GripCalibrationButtonPushed(app, event)
            Username = char(app.UsernameTextArea.Value);
            save('username','Username');            
            App_grip_calib
        end

        % Button pushed function: Label1ConfirmButton
        function Label1ConfirmButtonPushed(app, event)
            app.button1.Visible = true;
            app.button2.Visible = true;
            app.button3.Visible = true;
            app.TorqueStabilisationButton.Visible = true;
            app.PositionTrackingButton.Visible = true;
            
        end

        % Button pushed function: Label2ConfirmButton
        function Label2ConfirmButtonPushed(app, event)
            app.GripCalibrationButton.Visible = true;
            app.GripTargetTrackingButton.Visible = true;
            app.GripforceButton.Visible =true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GripforceButton = uibutton(app.UIFigure, 'push');
            app.GripforceButton.ButtonPushedFcn = createCallbackFcn(app, @GripforceButtonPushed, true);
            app.GripforceButton.Text = 'Grip force';
            app.TorqueStabilisationButton = uibutton(app.UIFigure, 'push');
            app.TorqueStabilisationButton.ButtonPushedFcn = createCallbackFcn(app, @TorqueStabilisationButtonPushed, true);
            app.TorqueStabilisationButton.Text = 'Torque Stabilisation';
            app.button3 = uibutton(app.UIFigure, 'push');
            app.button3.ButtonPushedFcn = createCallbackFcn(app, @button3Pushed, true);
            app.button3.Text = 'Zero-assisted';
            app.WelcometotheexperimentLabel = uilabel(app.UIFigure);
            app.WelcometotheexperimentLabel.Text = 'Welcome to the experiment!';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'I hope that the EEG and EMG electrodes are not annoying you too much. I''d like to invite you to a sequence of experiments constituting our study on wrist neuromechanics. During the following exercises you should be relaxed, avoid excessive blinking and swallowing. You should try to minimize the muscle contraction and stay calm. When you are ready, hit the Start button!';
            app.PleaseinputyournameTextAreaLabel = uilabel(app.UIFigure);
            app.PleaseinputyournameTextAreaLabel.Text = 'Please input your name:';
            app.UsernameTextArea = uitextarea(app.UIFigure);
            app.GripTargetTrackingButton = uibutton(app.UIFigure, 'push');
            app.GripTargetTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @GripTargetTrackingButtonPushed, true);
            app.GripTargetTrackingButton.Text = 'Grip Target Tracking';
            app.HI5TargetTrackingLabel = uilabel(app.UIFigure);
            app.HI5TargetTrackingLabel.Text = 'HI5 Target Tracking:';
            app.button1 = uibutton(app.UIFigure, 'push');
            app.button1.ButtonPushedFcn = createCallbackFcn(app, @button1Pushed, true);
            app.button1.Text = 'Full-assisted';
            app.button2 = uibutton(app.UIFigure, 'push');
            app.button2.ButtonPushedFcn = createCallbackFcn(app, @button2Pushed, true);
            app.button2.Text = 'Semi-assisted';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save Data';
            app.PositionTrackingButton = uibutton(app.UIFigure, 'push');
            app.PositionTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @PositionTrackingButtonPushed, true);
            app.PositionTrackingButton.Text = 'Position Tracking';
            app.GripCalibrationButton = uibutton(app.UIFigure, 'push');
            app.GripCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @GripCalibrationButtonPushed, true);
            app.GripCalibrationButton.Text = {'Grip Calibration'; ''};
            app.count1 = uilabel(app.UIFigure);
            app.count1.Text = '';
            app.count2 = uilabel(app.UIFigure);
            app.count2.Text = '';
            app.count3 = uilabel(app.UIFigure);
            app.count3.Text = '';
            app.RunRDAUDPRobotExpScriptChecktrialnumbersLabel = uilabel(app.UIFigure);
            app.RunRDAUDPRobotExpScriptChecktrialnumbersLabel.Text = 'Run RDA, UDP Robot, Exp Script, Check trial numbers';
            app.RunGripPythonLabel = uilabel(app.UIFigure);
            app.RunGripPythonLabel.Text = 'Run Grip Python';
            app.Label1ConfirmButton = uibutton(app.UIFigure, 'push');
            app.Label1ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @Label1ConfirmButtonPushed, true);
            app.Label1ConfirmButton.Text = 'Confirm';
            app.Label2ConfirmButton = uibutton(app.UIFigure, 'push');
            app.Label2ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @Label2ConfirmButtonPushed, true);
            app.Label2ConfirmButton.Text = 'Confirm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AMainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end