classdef App_AngleDisp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        StartButton             matlab.ui.control.Button
        AngledegEditFieldLabel  matlab.ui.control.Label
        AngledegEditField       matlab.ui.control.NumericEditField
        descriptionLabel        matlab.ui.control.Label
        TaskLabel               matlab.ui.control.Label
        instructionLabel        matlab.ui.control.Label
        blue                    matlab.ui.control.Image
        red                     matlab.ui.control.Image
        counter                 matlab.ui.control.Label
        trialCounter            matlab.ui.control.Label
        PauseButton             matlab.ui.control.StateButton
        NextButton              matlab.ui.control.Button
        StartPositionButton     matlab.ui.control.Button
        speed                   matlab.ui.control.Label
        TrainingButton          matlab.ui.control.Button
        StartTrainingButton     matlab.ui.control.Button
        logic                   matlab.ui.control.Button
        logic_2                 matlab.ui.control.Button
        enter                   matlab.ui.control.Image
        rightArrow              matlab.ui.control.Image
        tKey                    matlab.ui.control.Image
        space                   matlab.ui.control.Image
        logic_3Button           matlab.ui.control.StateButton
        ErrorTextAreaLabel      matlab.ui.control.Label
        ErrorTextArea           matlab.ui.control.TextArea
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
    end


    methods (Access = public)
        
function checkPauseStopStatus(app,flag)
        if app.PauseButton.Value
            app.counter.Visible=true;
            app.counter.Text='Paused'; 
            waitfor(app.PauseButton,'Value',false)
            app.counter.Visible=true;
            app.counter.Text='Ready..'; 
            pause(2);
            app.counter.Text='3';
            pause(1);
            app.counter.Text='2';
            pause(1);
            app.counter.Text='1';
            pause(1);
            app.counter.Visible=false;
            app.counter.Text=' '; 
        end
    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
        %start with hiding the unnecessary text
        app.space.Visible=true;
        app.enter.Visible=false;
        app.trialCounter.Visible=true;
        app.TaskLabel.Visible=false;
        app.descriptionLabel.Visible=false;
        app.StartButton.Visible=false;
        app.PauseButton.Visible=true;
        app.NextButton.Visible=false;
        app.ErrorTextAreaLabel.Visible = false;
        app.logic.Enable=false;
        app.logic_2.Enable = false;
        
        %variables
        hi5Target={};%cell aray for positional data
        hi5WristPos={};%cell aray for positional data
        hi5TargetPos={};
        hold = 10;
        rest = 5;
        numTrials=1;
        currentMaxP = 10000;
        currentMaxN = -10000;
        KW = 4; %8 //spring
        DW = 10; %1.1 //damper
        MW = 0; %0.001 //wall
        
        %initialize
        Motor1 = Epos4(0,0);
        Motor1.ClearErrorState;
        Motor1.DisableNode;
        Motor1.SetOperationMode( OperationModes.CurrentMode );
        Motor1.EnableNode;
        Motor1.ClearErrorState;
        
        startPosition = Motor1.ActualPosition;
        posError = 0;
        posErrorPrev = 0;
        posErrorDiff = 0;
        posErrorDiffPrev = 0;
        posErrorDiffDifff = 0;
      
        %circle calcs and display
        r=850;
        x=640;
        y=0;
        th = 0:pi/800:pi;
        xunit = r * cos(th) + x;
        yunit = r * sin(th) + y;
        xunit=xunit(201:601);
        yunit=yunit(201:601);
        plot(app.UIAxes,xunit,yunit, '--','color','blue');
        app.UIFigure.Position = [0 0 1280 960];
        framePos=app.UIFigure.Position;
        app.blue.Position=[framePos(1)/2,framePos(2)/2,50,50];
        app.UIAxes.Position = [20 0 1280 640];
        alpha=0:pi/20:2*pi;
        R=2;
        x_circle=R*cos(alpha);
        y_circle=R*sin(alpha);
        app.UIAxes2.Position = [framePos(1)/2 framePos(2)/2 40 40];
        plot(app.UIAxes2,x_circle,y_circle,'-')
        fill(app.UIAxes2,x_circle,y_circle,'b');
        
        trials = 1;
        sinPos=0;
        
        while (trials <=numTrials)
        app.trialCounter.Text=['Trial # ' num2str(trials)];
        
        if ~isempty(app)%pause button functionality
        checkPauseStopStatus(app)
        end
        
        c = clock;
        clockStart = c(4)*3600+c(5)*60+c(6);
        clockStartPrev = clockStart;
        posClockPrev = clockStart;

        %this is while loop for 3,2,1,ready counter
        while (clockStart < clockStartPrev + rest)
        c = clock;
        clockStart = c(4)*3600+c(5)*60+c(6);
        if(clockStart > clockStartPrev + 4)
            app.counter.Text='1';
        elseif (clockStart > clockStartPrev + 3)
            app.counter.Text='2';
        elseif (clockStart > clockStartPrev + 2)
            app.counter.Text='3';   

        elseif (clockStart > clockStartPrev)
            app.counter.Text='Ready..';   
        end
        

        posError = -(Motor1.ActualPosition - (startPosition));
        angDeviation = posError/6400*90;
        angDeviation = floor(angDeviation) + floor( (angDeviation-floor(angDeviation))/0.25) * 0.25;
        %app.AngledegEditField.Value=angDeviation;
        
        %limit movement to +-50 degs
        if angDeviation>50
            angDeviation=50;
        elseif angDeviation<-50
            angDeviation=-50;
        end
        
        blueX = xunit(4*(50.25));
        blueY= yunit(4*(50.25))-400;
        app.blue.Position=[blueX,blueY,50,50];
        app.UIAxes2.Position = [blueX,blueY,50,50];
        
        redX=xunit(4*(50.25-angDeviation));
        redY=yunit(4*(50.25-angDeviation))-400;
        app.red.Position=[redX+10,redY+10,30,30];
        pause(0.001)%for some reason this pause is needed for smootheness


        end
        
        app.counter.Text='';
        if ~isempty(app) %pause button functionality
        checkPauseStopStatus(app)
        end
        
        %initiate stuff before the trial loop
        c = clock;
        clockStart = c(4)*3600+c(5)*60+c(6);
        clockStartPrev = clockStart;
        posClockPrev = clockStart;
        hi5WristData=[];
        hi5TargetData=[];
        ErrorList = [];
        Trajectory_type = 0;
        
        while (clockStart < clockStartPrev + hold)
            app.ErrorTextArea.Visible = false;
            app.ErrorTextAreaLabel.Visible = false;
            c = clock;
            clockStart = c(4)*3600+c(5)*60+c(6);
            

            posError = -(Motor1.ActualPosition - (startPosition));
            angDeviation = posError/6400*90;
            angDeviation = floor(angDeviation) + floor( (angDeviation-floor(angDeviation))/0.25) * 0.25;
            %app.AngledegEditField.Value=angDeviation;
            
            if angDeviation>50
                angDeviation=50;
            elseif angDeviation<-50
                angDeviation=-50;
            end
            
            %%eqs below govern behaviour of the blue dot
            if mod(trials,3) == 1
                Trajectory_type = 1;
                sinTime=clockStart-clockStartPrev;
                sinPos=2*18.51*(sin(sinTime*pi/(1.547))*sin(sinTime*pi/(2.875)));%18.51
                sinPos = floor(sinPos) + floor( (sinPos-floor(sinPos))/0.25) * 0.25;
            elseif mod(trails,3) == 2
                Trajectory_type = 2;
                sinTime=clockStart-clockStartPrev+5.75;
                sinPos=2*18.51*(sin(sinTime*pi/(1.547))*sin(sinTime*pi/(2.875)));%18.51
                sinPos = floor(sinPos) + floor( (sinPos-floor(sinPos))/0.25) * 0.25;
            elseif mod(trails,3) == 0
                Trajectory_type = 3;
                sinTime=clockStart-clockStartPrev+8.625;
                sinPos=2*18.51*(sin(sinTime*pi/(1.547))*sin(sinTime*pi/(2.875)));%18.51
                sinPos = floor(sinPos) + floor( (sinPos-floor(sinPos))/0.25) * 0.25;
            end

            blueX = xunit(4*(50.25+sinPos));
            blueY= yunit(4*(50.25+sinPos))-400;
            app.blue.Position=[blueX,blueY,50,50];
            app.UIAxes2.Position = [blueX-20,blueY-20,50,50];
            
            hi5TargetData=[hi5TargetData sinPos];
            
            hi5TimeData = [hi5TimeData sinTime];
            
            redX=xunit(4*(50.25-angDeviation));
            redY=yunit(4*(50.25-angDeviation))-400;
            app.red.Position=[redX+10,redY+10,30,30];
            hi5WristData=[hi5WristData angDeviation];
            
            hi5Trajectory_type = [hi5Trajectory_type Trajectory_type];
            
            %ScorePerTrail = 100*abs(2*(blueX*redX+blueY*redY)-redX^2-redY^2)/abs(blueX^2 + blueY^2);
            ErrorPerTrail = sqrt((blueX-redX)^2+(blueY-redY)^2);
            ErrorList = [ErrorList ErrorPerTrail];
            Error = mean(ErrorList);
            colour = 1/(ErrorPerTrail+50);
            fill(app.UIAxes2,x_circle,y_circle,[0.7 0.7 1-colour*50]);
            
            pause(0.001)

        end
        app.ErrorTextAreaLabel.Visible = true;
        app.ErrorTextArea.Visible = true;
        app.ErrorTextArea.Value = num2str(Error,'%.2f');
        
        %save values from this trial
        name=strcat('trial',num2str(trials));
        hi5WristPos.(name) = hi5WristData;
        hi5TargetPos.(name) = hi5TargetData;
        hi5TrajectoryType.(name) = hi5Trajectory_type;
        hi5Time.(name) = hi5TimeData;
        trials = trials + 1;
        end
        hi5Target.hi5WristPos=hi5WristPos;
        hi5Target.hi5TargetPos=hi5TargetPos;
        hi5Target.hi5TrajectoryType=hi5TrajectoryType;
        hi5Target.hi5Time = hi5Time;
        save('hi5Target.mat','hi5Target')
        app.trialCounter.Visible=false;
        app.StartButton.Visible=false;
        app.PauseButton.Visible=false;
        app.space.Visible=false;
        app.TaskLabel.Visible=false;
        app.descriptionLabel.Visible=false;
        app.NextButton.Visible=true;
        app.rightArrow.Visible=true;
        app.instructionLabel.Text='Congratulations, you have finished this part of the experiment! To continue, press ';
        app.logic.Enable=true;
        app.logic_2.Enable=true;
        app.trialCounter.Text=' ';
            

        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
        if app.PauseButton.Value
            app.PauseButton.Text = 'Resume (Press Space)';
        else
            app.PauseButton.Text = 'Pause (Press Space)';
        end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.NextButton.Visible=false;
            app.rightArrow.Visible=false;
            app.space.Visible=false;
            app.tKey.Visible=true;
            app.enter.Visible=true;
            app.TaskLabel.Text='Match the position';
            %app.descriptionLabel.Text='                                                                 Set your wrist to neutral position, press                to start or               to train';
            app.descriptionLabel.Text='Set your wrist to neutral position, press                to train and                to start';
            app.logic_3Button.Enable=true;
            app.instructionLabel.Visible=true;
            app.instructionLabel.Text='Go to the marker position in a slow or fast manner, depending on the sped displayed. Train beforehand to learn the speeds.';
            app.TaskLabel.Visible=true;
            app.descriptionLabel.Visible=true;
            %app.instructionLabel.Visible=true;
            app.StartPositionButton.Visible=true;
            app.StartTrainingButton.Visible=true;


        end

        % Button pushed function: StartPositionButton
        function StartPositionButtonPushed(app, event)
        %this code foverns the 'Match the position' task
        app.enter.Visible=false;
        app.space.Visible=true;
        app.tKey.Visible=false;
        app.trialCounter.Visible=true;
        app.StartTrainingButton.Visible=false;
        app.TaskLabel.Visible=false;
        app.descriptionLabel.Visible=false;
        app.StartButton.Visible=false;
        app.PauseButton.Visible=true;
        app.NextButton.Visible=false; 
        app.speed.Visible=true;
        app.ErrorTextAreaLabel.Visible=false;
        app.ErrorTextArea.Visible = false;
        app.logic.Enable = false;
        app.logic_2.Enable = false;
        app.logic_3Button.Enable = false;
        
        %variables
        hi5Positioning={};%cell aray for wristPos and targetPos
        wristPos={};
        targetPos={};
        hold = 3;
        rest = 5;%length of 3,2,1
        numTrials=8;%must be divisible by 8. 8 trials cover going to each position (4) once and going back to zero
        numEpochs=2;
        positions=[];
        currentMaxP = 10000;
        currentMaxN = -10000;
        KW = 4; %8 //spring
        DW = 10; %1.1 //damper
        MW = 0; %0.001 //wall
        
        %initialize
        Motor1 = Epos4(0,0);
        Motor1.ClearErrorState;
        Motor1.DisableNode;
        Motor1.SetOperationMode( OperationModes.CurrentMode );
        Motor1.EnableNode;
        Motor1.ClearErrorState;
        
        startPosition = Motor1.ActualPosition;
        posError = 0;
        posErrorPrev = 0;
        posErrorDiff = 0;
        posErrorDiffPrev = 0;
        posErrorDiffDifff = 0;

      
        %circle calcs
        r=850;
        x=640;
        y=0;
        th = 0:pi/800:pi;
        xunit = r * cos(th) + x;
        yunit = r * sin(th) + y;
        xunit=xunit(201:601);
        yunit=yunit(201:601);
        plot(app.UIAxes,xunit,yunit, '--','color','blue');
        app.UIFigure.Position = [0 0 1280 960];
        framePos=app.UIFigure.Position;
        app.blue.Position=[framePos(1)/2,framePos(2)/2,40,40];
        app.UIAxes.Position = [20 0 1280 640];
        trials = 1;
        
        epoch=1;
        posFlag=0;
        
        
        while epoch <= numEpochs

        if epoch ==1
            speedFlag = 'Slow';
            app.speed.FontColor=[0 1 0];
        elseif epoch==2
            speedFlag = 'Fast';
            app.speed.FontColor=[1 0 0];
        elseif epoch ==3
            speedFlag = 'Alternating';
            app.speed.FontColor=[0 0 0];
        end
        app.speed.Text=speedFlag;


        %initialize stuff
        c = clock;
        clockStart = c(4)*3600+c(5)*60+c(6);
        clockStartPrev = clockStart;
        posClockPrev = clockStart;
        
        for i =1:1:numTrials/8
            positions = [positions 40 20 -10 -20];
        end

%         sample=randi(size(positions));
%         position=positions(sample);
%         positions(sample)=[];
        
        while (clockStart < clockStartPrev + rest)
        c = clock;
        clockStart = c(4)*3600+c(5)*60+c(6);
        if(clockStart > clockStartPrev + 4)
            app.counter.Text='1';
        elseif (clockStart > clockStartPrev + 3)
            app.counter.Text='2';
        elseif (clockStart > clockStartPrev + 2)
            app.counter.Text='3';   

        elseif (clockStart > clockStartPrev)
            app.counter.Text='Ready..';   
        end
        
        posError = -(Motor1.ActualPosition - (startPosition));
        angDeviation = posError/6400*90;
        angDeviation = floor(angDeviation) + floor( (angDeviation-floor(angDeviation))/0.25) * 0.25;
        %app.AngledegEditField.Value=angDeviation;
        
        if angDeviation>50
            angDeviation=50;
        elseif angDeviation<-50
            angDeviation=-50;
        end


        blueX = xunit(4*50.25);
        blueY= yunit(4*50.25)-400;
        app.blue.Position=[blueX,blueY,50,50];
        
        redX=xunit(4*(50.25-angDeviation));
        redY=yunit(4*(50.25-angDeviation))-400;
        app.red.Position=[redX+10,redY+10,30,30];
        pause(0.001)

        end
        if epoch==3
            speedFlag = 'Slow';
            app.speed.FontColor=[0 1 0];
            app.speed.Text=speedFlag;
        end
        trial=1;
        while (trial <=numTrials)
        app.trialCounter.Text=['Trial # ' num2str(trials)];
        
        if ~isempty(app)%pause button functionality
        checkPauseStopStatus(app)
        end

        app.counter.Text='';
        
        %%this is sed in third epoch where we alternate between fast and
        %%slow every two trials
        if mod(trial,2) && epoch==3
            if strcmp(speedFlag,'Slow')
                speedFlag = 'Fast';
                app.speed.FontColor=[1 0 0];
            elseif strcmp(speedFlag,'Fast')
                speedFlag = 'Slow';
                app.speed.FontColor=[0 1 0];
            end
        end
        app.speed.Text=speedFlag;
        
        %%initiate stuff
        c = clock;
        clockStart = c(4)*3600+c(5)*60+c(6);
        clockStartPrev = clockStart;
        posClockPrev = clockStart;
        prevClock=posClockPrev;
        wristData=[];
        targetData=[];
        

        executed =0;%this is logic variable for going back to zero position
           
        while (clockStart < clockStartPrev + hold)
            
            c = clock;
            clockStart = c(4)*3600+c(5)*60+c(6);
            posError = -(Motor1.ActualPosition - (startPosition));
            angDeviation = posError/6400*90;
            angDeviation = floor(angDeviation) + floor( (angDeviation-floor(angDeviation))/0.25) * 0.25;
            app.AngledegEditField.Value=angDeviation;
            if angDeviation>50
                angDeviation=50;
            elseif angDeviation<-50
                angDeviation=-50;
            end
            
            %%let's choose the next position
            if posFlag == 0 && executed ==0
                sample=randi(size(positions));
                position=positions(sample);
                positions(sample)=[];
                %position=randsample(positions,1);
                executed = 1;
            elseif posFlag == 1
                position = 0;
            end


            blueX = xunit(4*(50.25+position));
            blueY= yunit(4*(50.25+position))-400;
            app.blue.Position=[blueX,blueY,50,50];
            targetData=[targetData position];
            
            redX=xunit(4*(50.25-angDeviation));
            redY=yunit(4*(50.25-angDeviation))-400;
            app.red.Position=[redX+10,redY+10,30,30];
            wristData=[wristData angDeviation];
            pause(0.001)

        end
        if posFlag == 1
            posFlag = 0;
        elseif posFlag==0
            posFlag=1;
        end
        name=strcat('trial',num2str(trials));
        wristPos.(name) = wristData;
        targetPos.(name) = targetData;
        trial = trial+1;
        trials = trials + 1;
        end

        epoch=epoch+1;
        end
        hi5Positioning.wristPos=wristPos;
        hi5Positioning.level=targetPos;
        save('hi5Position.mat','hi5Positioning')
        app.trialCounter.Visible=false;
        app.StartButton.Visible=false;
        app.PauseButton.Visible=false;
        app.space.Visible=false;
        app.TaskLabel.Visible=false;
        app.descriptionLabel.Visible=false;
        app.instructionLabel.Text='This is the end of the experiment. Thank you!';
        app.speed.Visible=false;
        app.StartPositionButton.Visible=false;
        app.StartTrainingButton.Visible=false;
        
        end

        % Button pushed function: StartTrainingButton
        function StartTrainingButtonPushed(app, event)
        app.trialCounter.Visible=true;
        app.StartTrainingButton.Visible=false;
        app.enter.Visible=false;
        app.tKey.Visible=false;
        app.TaskLabel.Visible=false;
        app.descriptionLabel.Visible=false;
        app.StartButton.Visible=false;
        app.PauseButton.Visible=true;
        app.NextButton.Visible=false; 
        app.StartPositionButton.Visible=false;
        app.ErrorTextAreaLabel.Visible=false;
        app.ErrorTextArea.Visible = false;
        app.logic.Enable = false;
        app.logic_2.Enable = false;
        app.logic_3Button.Enable = false;
        
        %variables
        positionTrack={};
        wristPos1={};
        targetPos={};
        velocity={};
        hold = 3;
        rest = 5;
        numTrials=4;
        numEpochs=2;
        positions=[40 20 -10 -20];
        currentMaxP = 10000;
        currentMaxN = -10000;
        KW = 4; %8 //spring
        DW = 10; %1.1 //damper
        MW = 0; %0.001 //wall
        
        %initialize
        Motor1 = Epos4(0,0);
        Motor1.ClearErrorState;
        Motor1.DisableNode;
        Motor1.SetOperationMode( OperationModes.CurrentMode );
        Motor1.EnableNode;
        Motor1.ClearErrorState;
        
        startPosition = Motor1.ActualPosition;
        posError = 0;
        posErrorPrev = 0;
        posErrorDiff = 0;
        posErrorDiffPrev = 0;
        posErrorDiffDifff = 0;

      
        %circle calcs
        r=850;
        x=640;
        y=0;
        th = 0:pi/800:pi;
        xunit = r * cos(th) + x;
        yunit = r * sin(th) + y;
        xunit=xunit(201:601);
        yunit=yunit(201:601);
        plot(app.UIAxes,xunit,yunit, '--','color','blue');
        app.UIFigure.Position = [0 0 1280 960];
        framePos=app.UIFigure.Position;
        app.blue.Position=[framePos(1)/2,framePos(2)/2,40,40];
        app.UIAxes.Position = [20 0 1280 640];

        trials = 1;
        sinPos=0;

        if ~isempty(app)%pause button functionality
        checkPauseStopStatus(app)
        end
        
        c = clock;
        clockStart = c(4)*3600+c(5)*60+c(6);
        clockStartPrev = clockStart;
        posClockPrev = clockStart;

        
        while (clockStart < clockStartPrev + rest)
        c = clock;
        clockStart = c(4)*3600+c(5)*60+c(6);
        if(clockStart > clockStartPrev + 4)
            app.counter.Text='1';
        elseif (clockStart > clockStartPrev + 3)
            app.counter.Text='2';
        elseif (clockStart > clockStartPrev + 2)
            app.counter.Text='3';   

        elseif (clockStart > clockStartPrev)
            app.counter.Text='Ready..';   
        end


        posError = -(Motor1.ActualPosition - (startPosition));
        posErrorDiff = posError- posErrorPrev;
        angDeviation = posError/6400*90;
        angDeviation = floor(angDeviation) + floor( (angDeviation-floor(angDeviation))/0.25) * 0.25;
        %app.AngledegEditField.Value=angDeviation;
        %app.velocity.Value=posErrorDiff;
        if angDeviation>50
            angDeviation=50;
        elseif angDeviation<-50
            angDeviation=-50;
        end

        sinTime=clockStart-clockStartPrev;
        treshold=40;
        if abs(sinPos) < abs(treshold)
        sinPos=treshold/2*cos(sinTime)-treshold/2;
        else
            sinPos=-treshold;
        end
        
        sinPos = floor(sinPos) + floor( (sinPos-floor(sinPos))/0.25) * 0.25;
        blueX = xunit(4*(50.25));%-sinPos
        blueY= yunit(4*(50.25))-400;%-sinPos
        app.blue.Position=[blueX,blueY,50,50];
        
        redX=xunit(4*(50.25-angDeviation));
        redY=yunit(4*(50.25-angDeviation))-400;
        app.red.Position=[redX+10,redY+10,30,30];
        pause(0.001)
        posErrorPrev=posError;
        end
        epoch=1;
        
        while(epoch<=numEpochs)
            trial=1;

        app.counter.Text='';
        if ~isempty(app) %pause button functionality
        checkPauseStopStatus(app)
        end
        
        sinPos=0;
        sinPosPrev=0;
        while (trial <=numTrials)
        app.trialCounter.Text=['Trial # ' num2str(trials)];
        if epoch==1
            speedFlag='Slow';
            app.speed.FontColor=[0 1 0];
        elseif epoch==2
            speedFlag='Fast';
            app.speed.FontColor=[1 0 0];
        end
        app.speed.Visible=true;
        app.speed.Text=speedFlag;
        c = clock;
        clockStart = c(4)*3600+c(5)*60+c(6);
        clockStartPrev = clockStart;
        posClockPrev = clockStart;
        prevClock=posClockPrev;
        %startPosition = Motor1.ActualPosition;
        wristData=[];
        targetData=[];
        velocityData=[];


        tresholds=[40 -40 20 -20 -10 10 -20 20];
        treshold=tresholds(trial);
        if~mod(trial,2)
        sinPos=treshold;
        else
        sinPos=0;
        end
        while (clockStart < clockStartPrev + hold)
            
            c = clock;
            clockStart = c(4)*3600+c(5)*60+c(6);

            posError = -(Motor1.ActualPosition - (startPosition));
            %posErrorDiff = posError- posErrorPrev;
            angDeviation = posError/6400*90;
            angDeviation = floor(angDeviation) + floor( (angDeviation-floor(angDeviation))/0.25) * 0.25;
            app.AngledegEditField.Value=angDeviation;
            %app.velocity.Value=posErrorDiff;
            if angDeviation>50
                angDeviation=50;
            elseif angDeviation<-50
                angDeviation=-50;
            end
            
            sinTime=clockStart-clockStartPrev;
            if~mod(trials,2)
                if abs(sinPos) > 0.25
                sinPos=treshold+treshold/2*cos(1.5*epoch*((40/abs(treshold))^1.1)*sinTime)-treshold/2;
                %sinPos=treshold+treshold/2*cos(2*sinTime)-treshold/2;
                else
                    sinPos=0;
                end
            else
                if abs(sinPos) < abs(treshold)-0.25
                sinPos=treshold/2*cos(1.5*epoch*((40/abs(treshold))^1.1)*sinTime)-treshold/2;
                %sinPos=treshold/2*cos(2*sinTime)-treshold/2;
                else
                    sinPos=-treshold;
                end
            end
            
            %posErrorDiff=sinPos-sinPosPrev;
            posErrorDiff=posError-posErrorPrev;

            
            sinPos = floor(sinPos) + floor( (sinPos-floor(sinPos))/0.25) * 0.25;
            blueX = xunit(4*(50.25-sinPos));
            blueY= yunit(4*(50.25-sinPos))-400;
            app.blue.Position=[blueX,blueY,50,50];

            redX=xunit(4*(50.25-angDeviation));
            redY=yunit(4*(50.25-angDeviation))-400;
            app.red.Position=[redX+10,redY+10,30,30];
            wristData=[wristData angDeviation];
            posErrorPrev = posError;
            sinPosPrev=sinPos;
            velocityData=[velocityData abs(posErrorDiff)];
            pause(0.001)

        end
        name=strcat('trial',num2str(trials));
%         wristPos1.(name) = wristData;
%         targetPos.(name) = targetData;
        velocity.(name) = velocityData;
        trial=trial+1;
        trials = trials + 1;
        end
        epoch=epoch+1;
        end
%         positionTrack.wristPos=wristPos;
%         positionTrack.targetPos=targetPos;
        save('velocity.mat','velocity')
        app.trialCounter.Visible=false;
        app.StartButton.Visible=false;
        app.PauseButton.Visible=false;
        app.space.Visible=false;
        app.TaskLabel.Visible=false;
        app.descriptionLabel.Visible=false;
        app.instructionLabel.Text='Training finished. To continue press ';
        app.logic_2.Enable=true;
        app.rightArrow.Visible=true;
        app.instructionLabel.Visible=true;
        app.speed.Visible=false;
        app.trialCounter.Text=' ';
        

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'return'
                    if app.logic_2.Enable
                        StartPositionButtonPushed(app, event);
%                     else
%                         StartButtonPushed(app, event);
                    end
                case 'rightarrow'
                    if app.logic_2.Enable
                        NextButtonPushed(app, event);
                    end
                case 'space'
                    if app.PauseButton.Value == false
                        app.PauseButton.Value = true;
                    elseif app.PauseButton.Value == true
                        app.PauseButton.Value = false;
                    end
                case 't'
                    if app.logic_3Button.Enable
                        StartTrainingButtonPushed(app, event);
                    end
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            grip = load('grip.mat');
            gripTarget = load('gripTarget.mat');
            torque = load('Torque.mat');
            target = load('hi5Target.mat');
            positioning = load('hi5Position.mat');
            Username = char(load('username.mat').Username);
            data={};
            gripname = strcat(Username,'_',char(datetime('now','Format','yyyy_MM_dd''T''HHmm')),'_grip');
            torquename = strcat(Username,'_',char(datetime('now','Format','yyyy_MM_dd''T''HHmm')),'_torque');
            targetname = strcat(Username,'_',char(datetime('now','Format','yyyy_MM_dd''T''HHmm')),'_hi5Target');
            positionname = strcat(Username,'_',char(datetime('now','Format','yyyy_MM_dd''T''HHmm')),'_hi5Position');
            gripTargetname = strcat(Username,'_',char(datetime('now','Format','yyyy_MM_dd''T''HHmm')),'_gripTarget');
            
            data.(gripname) = grip;
            data.(gripTargetname) = gripTarget;
            data.(torquename)=torque;
            data.(targetname)=target;
            data.(positionname)=positioning;
            
            dataname = strcat(Username,'_',char(datetime('now','Format','yyyy_MM_dd''T''HHmm')));
            
            
            save(dataname,'data')
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'background line_e.png';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.AngledegEditFieldLabel = uilabel(app.UIFigure);
            app.AngledegEditFieldLabel.Visible = 'off';
            app.AngledegEditFieldLabel.Text = 'Angle[deg]';
            app.AngledegEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngledegEditField.Visible = 'off';
            app.descriptionLabel = uilabel(app.UIFigure);
            app.descriptionLabel.Text = 'Set your wrist to neutral position and press ';
            app.TaskLabel = uilabel(app.UIFigure);
            app.TaskLabel.Text = 'Follow the target';
            app.instructionLabel = uilabel(app.UIFigure);
            app.instructionLabel.Text = 'Try to follow the target position wihout tensing the forearm muscles too much';
            app.blue = uiimage(app.UIFigure);
            app.blue.ImageSource = 'blue dot.png';
            app.red = uiimage(app.UIFigure);
            app.red.ImageSource = 'red_dot.png';
            app.counter = uilabel(app.UIFigure);
            app.counter.Text = '';
            app.trialCounter = uilabel(app.UIFigure);
            app.trialCounter.Text = '';
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Visible = 'off';
            app.PauseButton.Text = 'Pause';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Visible = 'off';
            app.NextButton.Text = 'Next';
            app.StartPositionButton = uibutton(app.UIFigure, 'push');
            app.StartPositionButton.ButtonPushedFcn = createCallbackFcn(app, @StartPositionButtonPushed, true);
            app.StartPositionButton.Visible = 'off';
            app.StartPositionButton.Text = 'Start';
            app.speed = uilabel(app.UIFigure);
            app.speed.Text = '';
            app.TrainingButton = uibutton(app.UIFigure, 'push');
            app.TrainingButton.Visible = 'off';
            app.TrainingButton.Text = 'Training';
            app.StartTrainingButton = uibutton(app.UIFigure, 'push');
            app.StartTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainingButtonPushed, true);
            app.StartTrainingButton.Visible = 'off';
            app.StartTrainingButton.Text = 'Training';
            app.logic = uibutton(app.UIFigure, 'push');
            app.logic.Enable = 'off';
            app.logic.Visible = 'off';
            app.logic_2 = uibutton(app.UIFigure, 'push');
            app.logic_2.Enable = 'off';
            app.logic_2.Visible = 'off';
            app.enter = uiimage(app.UIFigure);
            app.enter.ImageSource = 'enter-key.png';
            app.rightArrow = uiimage(app.UIFigure);
            app.rightArrow.Visible = 'off';
            app.rightArrow.ImageSource = 'right arrow.png';
            app.tKey = uiimage(app.UIFigure);
            app.tKey.Visible = 'off';
            app.tKey.ImageSource = 'T key.png';
            app.space = uiimage(app.UIFigure);
            app.space.Visible = 'off';
            app.space.ImageSource = 'spacebar-icon-25.jpg';
            app.logic_3Button = uibutton(app.UIFigure, 'state');
            app.logic_3Button.Enable = 'off';
            app.logic_3Button.Visible = 'off';
            app.logic_3Button.Text = 'logic_3';
            app.ErrorTextAreaLabel = uilabel(app.UIFigure);
            app.ErrorTextAreaLabel.Visible = 'off';
            app.ErrorTextAreaLabel.Text = 'Error:';
            app.ErrorTextArea = uitextarea(app.UIFigure);
            app.ErrorTextArea.Visible = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_AngleDisp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end