classdef App_HI5_TargetTracking_ZeroAssisted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        StartButton             matlab.ui.control.Button
        AngledegEditFieldLabel  matlab.ui.control.Label
        AngledegEditField       matlab.ui.control.NumericEditField
        descriptionLabel        matlab.ui.control.Label
        TaskLabel               matlab.ui.control.Label
        instructionLabel        matlab.ui.control.Label
        blue                    matlab.ui.control.Image
        red                     matlab.ui.control.Image
        counter                 matlab.ui.control.Label
        PauseButton             matlab.ui.control.StateButton
        NextButton              matlab.ui.control.Button
        StartPositionButton     matlab.ui.control.Button
        speed                   matlab.ui.control.Label
        TrainingButton          matlab.ui.control.Button
        StartTrainingButton     matlab.ui.control.Button
        logic                   matlab.ui.control.Button
        logic_2                 matlab.ui.control.Button
        enter                   matlab.ui.control.Image
        rightArrow              matlab.ui.control.Image
        tKey                    matlab.ui.control.Image
        space                   matlab.ui.control.Image
        logic_3Button           matlab.ui.control.StateButton
        ScoreTextAreaLabel      matlab.ui.control.Label
        ScoreTextArea           matlab.ui.control.TextArea
        trialCounter            matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end


    methods (Access = public)
        
function checkPauseStopStatus(app,flag)
        clear u_pause
        u_pause = udpport('LocalPort', 2222);
        if app.PauseButton.Value
            app.counter.Visible=true;
            app.counter.Text='Paused'; 
            write(u_pause, strcat(num2str(app.PauseButton.Value)), 'string','LocalHost', 1111);
            waitfor(app.PauseButton,'Value',false)
            write(u_pause, strcat(num2str(app.PauseButton.Value)), 'string','LocalHost', 1111);
        end
    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
        %start with hiding the unnecessary text
        app.space.Visible=true;
        app.enter.Visible=false;
        app.trialCounter.Visible=true;
        app.TaskLabel.Visible=false;
        app.descriptionLabel.Visible=false;
        app.StartButton.Visible=false;
        app.PauseButton.Visible=true;
        app.NextButton.Visible=false;
        app.ScoreTextAreaLabel.Visible = true;
        app.logic.Enable=false;
        app.logic_2.Enable = false;
        
        %init udp
        clear u1 %reading in App from script
        clear u2 %writing in script
        clear u3 % writing in app
        clear u4 %reading in script from app
        u1 = udpport("LocalPort",3000); % open udp for FES pw from simulink, clear port if error
        u3 = udpport('LocalPort', 2000);% udp for writing in app to send data to script
        
        %variables
        experiment = 3;
        trial_length = 10;
        countdown = 3;
        numTrials = 5;
        data_box = [];
        
        %circle calcs and display
        r=850;
        x=640;
        y=0;
        th = 0:pi/800:pi;
        xunit = r * cos(th) + x;
        yunit = r * sin(th) + y;
        xunit=xunit(201:601);
        yunit=yunit(201:601);
        plot(app.UIAxes,xunit,yunit, '--','color','white',"LineWidth",2);
        set(app.UIAxes,'Color',[0.5 0.5 0.5])
        set(app.UIAxes,'visible','off')
        app.UIFigure.Position = [0 0 1280 960];
        framePos=app.UIFigure.Position;
        app.blue.Position=[framePos(1)/2,framePos(2)/2,40,40];
        app.UIAxes.Position = [10 10 1280 640];
%         data_int_array = int8(ones(1,124));
        
        app.ScoreTextArea.Value = num2str(0);
        
        trials = 1;
        trial_pre = 1;
        write(u3, strcat(num2str(experiment),uniform_data_trials(numTrials),uniform_data_trials(trial_length),uniform_data_trials(0)), 'string','LocalHost', 1000);
        flush(u1)
        
        tic
        
        while (trials <=numTrials)
            app.trialCounter.Text=['Trial # ' num2str(trials)];
            
            if ~isempty(app)%pause button functionality
                checkPauseStopStatus(app)
            end
            %this is while loop for 3,2,1,ready counter
            
            app.counter.Text='';
            c = clock;
            clockStart = c(4)*3600+c(5)*60+c(6);
            clockCurrent = clockStart;
            
            Elapsed_Time = clockCurrent - clockStart;
            %start of the trial marker 
            flush(u1)
            
            j = 1;
            
            while (Elapsed_Time(1) < trial_length + countdown)
                flush(u1)
%                 app.ScoreTextArea.Visible = false;
%                 app.ScoreTextAreaLabel.Visible = false;
                c = clock;
                clockCurrent = c(4)*3600+c(5)*60+c(6);
                Elapsed_Time = clockCurrent - clockStart;
                if (Elapsed_Time(1) > 3)
                    app.counter.Text = '';
                elseif(Elapsed_Time(1) > 2.25)
                    app.counter.Text='1';
                elseif (Elapsed_Time(1) > 1.5)
                    app.counter.Text='2';
                elseif (Elapsed_Time(1) > 0.75)
                    app.counter.Text='3';   
        
                elseif (Elapsed_Time(1) > 0)
                    app.counter.Text='Ready..';   
                end
                
                if (trials == numTrials) && (Elapsed_Time > trial_length + countdown - 0.2)
                    trials = trials + 1;
                    break
                end
%                 flush(u1); 

%                 data_int_array = int8(read(u1,124,"int8"));  % ensure buffer is multiple of number of electrodes used 
%                 for i = 1:4:121
%                     single_data_sample = typecast(data_int_array(i:i+3),'single');
%                     data_box = [data_box single_data_sample];
%                 end
%                 target_traj = data_box(1:10);
%                 subject_traj = data_box(11:20);
%                 Error = data_box(21);
% %                 Elapsed_Time = data_box(22:31);
%                 trial_num = data_box(22:31);
%                 data_box = [];
%                 
                data_int_array = int8(read(u1,16,"int8"));  % ensure buffer is multiple of number of electrodes used 
                for i = 1:4:13
                    single_data_sample = typecast(data_int_array(i:i+3),'single');
                    data_box = [data_box single_data_sample];
                end
                target_traj = data_box(1);
                subject_traj = data_box(2);
                Error = data_box(3);
%                 Elapsed_Time = data_box(22:31);
                trial_num = data_box(4);
                data_box = [];
                
%                 app.TaskLabel.Text = num2str(subject_traj);
                
                trials = trial_num(1);
                if trial_pre ~= trials
                    flush(u1);
                    trial_pre = trials;
                    break
                end
                
                k = 1;
%                 while k <= 10
%                     % edge of trajectory
%                     if subject_traj(k)>50
%                         subject_traj(k)=50;
%                     elseif subject_traj(k)<-50
%                         subject_traj(k)=-50;
%                     end
%                     
%                     a = toc;
%                     tic
%                     
%                     app.TaskLabel.Visible=true;
%                     
%                     if mod(k,4) == 0
%                     
%                         target_traj_sample = floor(target_traj(k)) + floor( (target_traj(k)-floor(target_traj(k)))/0.25) * 0.25;
%                         blueX = xunit(4*(50.25+target_traj_sample))-20;
%                         blueY= yunit(4*(50.25+target_traj_sample))-400;
%                         app.blue.Position=[blueX,blueY,50,50];
%                         
%                         subject_traj_sample = floor(subject_traj(k)) + floor( (subject_traj(k)-floor(subject_traj(k)))/0.25) * 0.25;
%                         redX=xunit(4*(50.25-subject_traj_sample))-20;
%                         redY=yunit(4*(50.25-subject_traj_sample))-400;
%                         app.red.Position=[redX+10,redY+10,30,30];
% %                         drawnow limitrate
%                         
% %                         app.TaskLabel.Text = num2str(k);
%                     end
%                     
%                     app.TaskLabel.Text = num2str(a);
% %                     pause(0.001)
%                     k = k+1;
%                 end
%                 pause(0.001)
%                 pause(0.0001)
                
%                     edge of trajectory
%                 if mod(j,2) == 0
                if subject_traj(k)>50
                    subject_traj(k)=50;
                elseif subject_traj(k)<-50
                    subject_traj(k)=-50;
                end
                
                a = toc;
                tic
                
%                 app.TaskLabel.Visible=true;
                
                target_traj_sample = floor(target_traj(k)) + floor( (target_traj(k)-floor(target_traj(k)))/0.25) * 0.25;
                blueX = xunit(4*(50.25+target_traj_sample))-20;
                blueY= yunit(4*(50.25+target_traj_sample))-400;
                app.blue.Position=[blueX,blueY,50,50];
                
                subject_traj_sample = floor(subject_traj(k)) + floor( (subject_traj(k)-floor(subject_traj(k)))/0.25) * 0.25;
                redX=xunit(4*(50.25-subject_traj_sample))-20;
                redY=yunit(4*(50.25-subject_traj_sample))-400;
                app.red.Position=[redX+10,redY+10,30,30];
                
%                 app.TaskLabel.Text = num2str(k);
                pause(0.001)
%                     drawnow limitrate
%                     app.Label.Text = num2str(Elapsed_Time(10));
%                 end
%                 j = j+1;
        
            end
            app.ScoreTextArea.Value = num2str(floor(Error));
            
        end
        app.blue.Position=[615,455,50,50];
        app.red.Position=[625,465,30,30];

        app.trialCounter.Visible=false;
        app.StartButton.Visible=false;
        app.PauseButton.Visible=false;
        app.space.Visible=false;
        app.TaskLabel.Visible=false;
        app.descriptionLabel.Visible=false;
        app.NextButton.Visible=true;
        app.rightArrow.Visible=true;
        app.instructionLabel.Text='Congratulations, you have finished this part of the experiment! To continue, press ';
        app.logic.Enable=true;
        app.logic_2.Enable=true;
        app.trialCounter.Text=' ';
            

        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
        if app.PauseButton.Value
            app.PauseButton.Text = 'Resume (Press Space)';
        else
            app.PauseButton.Text = 'Pause (Press Space)';
        end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'return'
                    if app.logic.Enable
                        StartPositionButtonPushed(app, event);
                    end
                case 'rightarrow'
                    if app.logic_2.Enable
                        NextButtonPushed(app, event);
                    end
                case 'space'
                    if app.PauseButton.Value == false
                        app.PauseButton.Value = true;
                    elseif app.PauseButton.Value == true
                        app.PauseButton.Value = false;
                    end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'background line_e.png';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.AngledegEditFieldLabel = uilabel(app.UIFigure);
            app.AngledegEditFieldLabel.Visible = 'off';
            app.AngledegEditFieldLabel.Text = 'Angle[deg]';
            app.AngledegEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngledegEditField.Visible = 'off';
            app.descriptionLabel = uilabel(app.UIFigure);
            app.descriptionLabel.Text = 'Set your wrist to neutral position and press ';
            app.TaskLabel = uilabel(app.UIFigure);
            app.TaskLabel.Text = 'Follow the target';
            app.instructionLabel = uilabel(app.UIFigure);
            app.instructionLabel.Text = 'Try to follow the target position wihout tensing the forearm muscles too much';
            app.blue = uiimage(app.UIFigure);
            app.blue.ImageSource = 'blue dot.png';
            app.red = uiimage(app.UIFigure);
            app.red.ImageSource = 'red_dot.png';
            app.counter = uilabel(app.UIFigure);
            app.counter.Text = '';
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Visible = 'off';
            app.PauseButton.Text = 'Pause';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.Visible = 'off';
            app.NextButton.Text = 'Next';
            app.StartPositionButton = uibutton(app.UIFigure, 'push');
            app.StartPositionButton.Visible = 'off';
            app.StartPositionButton.Text = 'Start';
            app.speed = uilabel(app.UIFigure);
            app.speed.Text = '';
            app.TrainingButton = uibutton(app.UIFigure, 'push');
            app.TrainingButton.Visible = 'off';
            app.TrainingButton.Text = 'Training';
            app.StartTrainingButton = uibutton(app.UIFigure, 'push');
            app.StartTrainingButton.Visible = 'off';
            app.StartTrainingButton.Text = 'Training';
            app.logic = uibutton(app.UIFigure, 'push');
            app.logic.Enable = 'off';
            app.logic.Visible = 'off';
            app.logic_2 = uibutton(app.UIFigure, 'push');
            app.logic_2.Enable = 'off';
            app.logic_2.Visible = 'off';
            app.enter = uiimage(app.UIFigure);
            app.enter.ImageSource = 'enter-key.png';
            app.rightArrow = uiimage(app.UIFigure);
            app.rightArrow.Visible = 'off';
            app.rightArrow.ImageSource = 'right arrow.png';
            app.tKey = uiimage(app.UIFigure);
            app.tKey.Visible = 'off';
            app.tKey.ImageSource = 'T key.png';
            app.space = uiimage(app.UIFigure);
            app.space.Visible = 'off';
            app.space.ImageSource = 'spacebar-icon-25.jpg';
            app.logic_3Button = uibutton(app.UIFigure, 'state');
            app.logic_3Button.Enable = 'off';
            app.logic_3Button.Visible = 'off';
            app.logic_3Button.Text = 'logic_3';
            app.ScoreTextAreaLabel = uilabel(app.UIFigure);
            app.ScoreTextAreaLabel.Text = 'Score:';
            app.ScoreTextArea = uitextarea(app.UIFigure);
            app.trialCounter = uilabel(app.UIFigure);
            app.trialCounter.Text = '';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_HI5_TargetTracking_ZeroAssisted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end