classdef App_HI5_torque < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        darkRed             matlab.ui.control.Image
        green               matlab.ui.control.Image
        yellow              matlab.ui.control.Image
        descriptionLabel    matlab.ui.control.Label
        TaskLabel           matlab.ui.control.Label
        instructionLabel    matlab.ui.control.Label
        StartButton         matlab.ui.control.Button
        blue                matlab.ui.control.Image
        red                 matlab.ui.control.Image
        AngledegEditField   matlab.ui.control.NumericEditField
        counter             matlab.ui.control.Label
        NextButton          matlab.ui.control.Button
        logic               matlab.ui.control.Button
        PauseButton         matlab.ui.control.StateButton
        enter               matlab.ui.control.Image
        rightArrow          matlab.ui.control.Image
        upArrow             matlab.ui.control.Image
        space               matlab.ui.control.Image
        logic_2Button       matlab.ui.control.StateButton
        trialCounter        matlab.ui.control.Label
        ScoreTextAreaLabel  matlab.ui.control.Label
        ScoreTextArea       matlab.ui.control.TextArea
        UIAxes              matlab.ui.control.UIAxes
    end

    methods (Access = public)
        
function checkPauseStopStatus(app,flag)
        clear u_pause
        u_pause = udpport('LocalPort', 2222);
        if app.PauseButton.Value
            app.counter.Visible=true;
            app.counter.Text='Paused'; 
            write(u_pause, strcat(num2str(app.PauseButton.Value)), 'string','LocalHost', 1111);
            waitfor(app.PauseButton,'Value',false)
            write(u_pause, strcat(num2str(app.PauseButton.Value)), 'string','LocalHost', 1111);
        end
    end
    end 

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
        %start with hiding the unnecessary text
        app.enter.Visible=false;
        app.space.Visible=true;
        app.trialCounter.Visible=true;
        app.TaskLabel.Visible=false;
        app.descriptionLabel.Visible=false;
        app.StartButton.Visible=false;
        app.PauseButton.Visible=true;
        app.logic_2Button.Enable=false;

        %init udp
        clear u1 %reading in App from script
        clear u2 %writing in script
        clear u3 % writing in app
        clear u4 %reading in script from app
        u1 = udpport("LocalPort",3000); % open udp for FES pw from simulink, clear port if error
        u3 = udpport('LocalPort', 2000);% udp for writing in app to send data to script
        
        
        %variables
        experiment = 5;
        trial_length = 6.5;
        countDown = 5;
        numTrials=50;%must be divisible by 3
        numblocks=3;
        data_box = [];
        
        write(u3, strcat(num2str(experiment),uniform_data_trials(numTrials),uniform_data_trials(trial_length),uniform_data_trials(numblocks)), 'string','LocalHost', 1000);
        
        %circle calcs
        r=850;
        x=640;
        y=0;
        th = 0:pi/800:pi;
        xunit = r * cos(th) + x;
        yunit = r * sin(th) + y;
        xunit=xunit(201:601);
        yunit=yunit(201:601);
        plot(app.UIAxes,xunit,yunit, '--','color','white','LineWidth',2);
        set(app.UIAxes,'Color',[0.5 0.5 0.5])
        set(app.UIAxes,'visible','off')
        app.UIFigure.Position = [0 0 1280 960];
        framePos=app.UIFigure.Position;
        app.blue.Position=[framePos(1)/2,framePos(2)/2,40,40];
        app.UIAxes.Position = [0 0 1280 640];
        
        trial_index = 1;
        trial_pre = 1;
        block=1;
        
        app.blue.Position=[615,455,50,50];
        app.green.Position=[615,455,50,50];
        app.yellow.Position=[615,455,50,50];
        app.darkRed.Position=[615,455,50,50];
        
        app.ScoreTextArea.Value = num2str(0);
        
        flush(u1);
        while (block <=numblocks)
            c = clock;
            clockCurrent = c(4)*3600+c(5)*60+c(6);
            clockStart = clockCurrent;
%             pause(0.005)
            trial_in_block = 1;
            app.counter.Text='';
            flush(u1);
            while(trial_index <= numTrials*numblocks)
                
                if mod(trial_index, numTrials) == 1
                    
                    if ~isempty(app)%pause button functionality
                        checkPauseStopStatus(app)
                    end
                    
                    
                    c = clock;
                    clockCurrent = c(4)*3600+c(5)*60+c(6);
                    clockStart = clockCurrent;
                    
                    app.blue.Visible=true;
                    app.green.Visible=false;
                    app.yellow.Visible=false;
                    app.darkRed.Visible=false;
                    
                    while (clockCurrent < clockStart + countDown)
                        c = clock;
                        clockCurrent = c(4)*3600+c(5)*60+c(6);
                        app.counter.Visible=true;
                        if(clockCurrent > clockStart + 4)
                            app.counter.Text='1';
                        elseif (clockCurrent > clockStart + 3)
                            app.counter.Text='2';
                        elseif (clockCurrent > clockStart + 2)
                            app.counter.Text='3';   
                
                        elseif (clockCurrent > clockStart)
                            app.counter.Text='Ready..';   
                        end
                        
                        data_int_array = int8(read(u1,124,"int8"));  % ensure buffer is multiple of number of electrodes used 
                        for i = 1:4:121
                            single_data_sample = typecast(data_int_array(i:i+3),'single');
                            data_box = [data_box single_data_sample];
                        end
                        subject_traj = data_box(11:20);
                        
                        data_box = [];
                        
                        subject_traj = floor(subject_traj) + floor( (subject_traj-floor(subject_traj))./0.25) .* 0.25;
                        
                        for i = 1:1:length(subject_traj)
                            subject_traj_sample = subject_traj(i);
                            if subject_traj_sample>50
                                subject_traj_sample=50;
                            elseif subject_traj_sample<-50
                                subject_traj_sample=-50;
                            end
                            subject_traj(i) = subject_traj_sample;
                        end
                        
                        redX=xunit(4.*(50.25-subject_traj))-20;
                        redY=yunit(4.*(50.25-subject_traj))-395;
                        
                        k = 1;
                        while k <= 10
                            app.red.Position=[redX(k)+10,redY(k)+10,30,30];
                            k = k+1;
                        end
%                         pause(0.001)
                
                    end
                    
                end
                
                app.counter.Text = '';
                app.trialCounter.Text=(num2str(trial_index));
                c = clock;
                clockCurrent = c(4)*3600+c(5)*60+c(6);
                clockStart = clockCurrent;
                Elapsed_Time = 0;
                
                
                while (Elapsed_Time < trial_length)
                    c = clock;
                    clockCurrent = c(4)*3600+c(5)*60+c(6);
                    Elapsed_Time = clockCurrent-clockStart;
                    
                    if (trial_index == numTrials*numblocks) && (Elapsed_Time > trial_length - 0.2)
                        trial_index = trial_index + 1;
                        break
                    end
                    
                    data_int_array = int8(read(u1,124,"int8"));  % ensure buffer is multiple of number of electrodes used 
                    for i = 1:4:121
                        single_data_sample = typecast(data_int_array(i:i+3),'single');
                        data_box = [data_box single_data_sample];
                    end
                    strength = data_box(1:10);
                    subject_traj = data_box(11:20);
                    Error = data_box(21);
                    trial_num = data_box(22:31);
                    
                    data_box = [];
                    
                    trial_index = trial_num(1);
                    if trial_pre ~= trial_index
                        flush(u1);
                        trial_pre = trial_index;
                        break
                    end
                    
                    
                    subject_traj = floor(subject_traj) + floor( (subject_traj-floor(subject_traj))./0.25) .* 0.25;
                    
                    for i = 1:1:length(subject_traj)
                        subject_traj_sample = subject_traj(i);
                        if subject_traj_sample>50
                            subject_traj_sample=50;
                        elseif subject_traj_sample<-50
                            subject_traj_sample=-50;
                        end
                        subject_traj(i) = subject_traj_sample;
                    end
                    
                    redX=xunit(4.*(50.25-subject_traj))-20;
                    redY=yunit(4.*(50.25-subject_traj))-395;
                    
                    k = 1;
                    while k <= 10
                        app.red.Position=[redX(k)+10,redY(k)+10,30,30];
                        k = k+1;
                    end
                    
                    switch strength(1)
                        case 0
                            app.blue.Visible=true;
                            app.green.Visible=false;
                            app.yellow.Visible=false;
                            app.darkRed.Visible=false;
                        case 1
                            app.blue.Visible=false;
%                             app.green.Visible=true;
%                             app.yellow.Visible=false;
                            app.darkRed.Visible=true;
                        case 2
                            app.blue.Visible=false;
%                             app.green.Visible=false;
%                             app.yellow.Visible=true;
                            app.darkRed.Visible=true;
                        case 3
                            app.blue.Visible=false;
%                             app.green.Visible=false;
%                             app.yellow.Visible=false;
                            app.darkRed.Visible=true;
                    end


%                     pause(0.001)
                end
                app.ScoreTextArea.Value = num2str(floor(Error));
                trial_in_block = trial_in_block + 1;%this is within a single block
%                 trial_index = trial_index + 1;%this is across blocks for data storage(counter and filenames)
            end
            block = block+1;
            app.TaskLabel.Visible=true;
            app.upArrow.Visible=true;
            app.TaskLabel.Text='To continue press ';
            app.TaskLabel.Visible=false;
            app.upArrow.Visible=false;
        end
        
        app.trialCounter.Visible=false;
        app.StartButton.Visible=false;
        app.PauseButton.Visible=false;
        app.space.Visible=false;
        app.TaskLabel.Visible=false;
        app.descriptionLabel.Visible=false;
        app.NextButton.Visible=true;
        app.instructionLabel.Text='Congratulations, you have finished this part of the experiment! To continue, press ';
        app.logic_2Button.Enable=true;
        app.rightArrow.Visible=true;
        app.UIAxes.Visible=false;
        app.red.Visible=false;
        app.blue.Visible=false;  
        
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            App_AngleDisp;
            app.delete;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'return'
                    StartButtonPushed(app, event);
                case 'rightarrow'
                    if app.logic_2Button.Enable
                        NextButtonPushed(app, event);
                    end
                case 'space'
                    if app.PauseButton.Value == false
                        app.PauseButton.Value = true;
                    elseif app.PauseButton.Value == true
                        app.PauseButton.Value = false;
                    end
                case 'uparrow'
                    app.logic.Enable=true;
            end

        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
        if app.PauseButton.Value
            app.PauseButton.Text = 'Resume (Press Space)';
        else
            app.PauseButton.Text = 'Pause (Press Space)';
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.darkRed = uiimage(app.UIFigure);
            app.darkRed.Visible = 'off';
            app.darkRed.ImageSource = 'dark_Red_dot_cricle.png';
            app.green = uiimage(app.UIFigure);
            app.green.Visible = 'off';
            app.green.ImageSource = 'green dot.png';
            app.yellow = uiimage(app.UIFigure);
            app.yellow.Visible = 'off';
            app.yellow.ImageSource = 'Yellow_icon.png';
            app.descriptionLabel = uilabel(app.UIFigure);
            app.descriptionLabel.Text = 'Set your wrist to neutral position and press ';
            app.TaskLabel = uilabel(app.UIFigure);
            app.TaskLabel.Text = 'Stabilise the torque';
            app.instructionLabel = uilabel(app.UIFigure);
            app.instructionLabel.Text = 'Try to maintain the wrist position by counteracting the effect of external torque';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.blue = uiimage(app.UIFigure);
            app.blue.ImageSource = 'blue dot.png';
            app.red = uiimage(app.UIFigure);
            app.red.ImageSource = 'red_dot.png';
            app.AngledegEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngledegEditField.Visible = 'off';
            app.counter = uilabel(app.UIFigure);
            app.counter.Visible = 'off';
            app.counter.Text = '';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Visible = 'off';
            app.NextButton.Text = 'Next';
            app.logic = uibutton(app.UIFigure, 'push');
            app.logic.Enable = 'off';
            app.logic.Visible = 'off';
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Visible = 'off';
            app.PauseButton.Text = 'Pause';
            app.enter = uiimage(app.UIFigure);
            app.enter.ImageSource = 'enter-key.png';
            app.rightArrow = uiimage(app.UIFigure);
            app.rightArrow.Visible = 'off';
            app.rightArrow.ImageSource = 'right arrow.png';
            app.upArrow = uiimage(app.UIFigure);
            app.upArrow.Visible = 'off';
            app.upArrow.ImageSource = 'up arrow.png';
            app.space = uiimage(app.UIFigure);
            app.space.Visible = 'off';
            app.space.ImageSource = 'spacebar-icon-25.jpg';
            app.logic_2Button = uibutton(app.UIFigure, 'state');
            app.logic_2Button.Enable = 'off';
            app.logic_2Button.Visible = 'off';
            app.logic_2Button.Text = 'logic_2';
            app.trialCounter = uilabel(app.UIFigure);
            app.trialCounter.Text = '';
            app.ScoreTextAreaLabel = uilabel(app.UIFigure);
            app.ScoreTextAreaLabel.Text = 'Score:';
            app.ScoreTextArea = uitextarea(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_HI5_torque

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end