classdef App_grip < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        descriptionLabel  matlab.ui.control.Label
        TaskLabel         matlab.ui.control.Label
        instructionLabel  matlab.ui.control.Label
        StartButton       matlab.ui.control.Button
        blue              matlab.ui.control.Image
        trialCounter      matlab.ui.control.Label
        counter           matlab.ui.control.Label
        darkRed           matlab.ui.control.Image
        green             matlab.ui.control.Image
        yellow            matlab.ui.control.Image
        red               matlab.ui.control.Image
        NextButton        matlab.ui.control.Button
        logic             matlab.ui.control.Button
        PauseButton       matlab.ui.control.StateButton
        enter             matlab.ui.control.Image
        rightArrow        matlab.ui.control.Image
        upArrow           matlab.ui.control.Image
        space             matlab.ui.control.Image
        UIAxes            matlab.ui.control.UIAxes
    end

    methods (Access = public)
        
function checkPauseStopStatus(app,flag)
        if app.PauseButton.Value
            app.counter.Visible=true;
            app.counter.Text='Paused'; 
            waitfor(app.PauseButton,'Value',false)
            app.counter.Visible=true;
            app.counter.Text='Ready..'; 
            pause(2);
            app.counter.Text='3';
            pause(1);
            app.counter.Text='2';
            pause(1);
            app.counter.Text='1';
            pause(1);
            app.counter.Visible=false;
            app.counter.Text=' '; 
        end
    end
end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
                         %start with hiding the unnecessary text
        app.enter.Visible=false;
        app.space.Visible=true;
        app.trialCounter.Visible=true;
        app.TaskLabel.Visible=false;
        app.descriptionLabel.Visible=false;
        app.StartButton.Visible=false;
        app.PauseButton.Visible=true;

        %variables
        grip={};%cell aray for positional data
        gripForce={};%cell aray for positional data
        level={};
        hold = 5.5;
        rest = 5;
        numTrials=10;
        numEpochs=2;
        currentMaxP = 10000;
        currentMaxN = -10000;
        KW = 4; %8 //spring
        DW = 10; %1.1 //damper
        MW = 0; %0.001 //wall
        
        %initialize
        Motor1 = Epos4(0,0);
        Motor1.ClearErrorState;
        Motor1.DisableNode;
        Motor1.SetOperationMode( OperationModes.CurrentMode );
        Motor1.EnableNode;
        Motor1.ClearErrorState;
        
        startPosition = Motor1.ActualPosition;
        posError = 0;
        posErrorPrev = 0;
        posErrorDiff = 0;
        posErrorDiffPrev = 0;
        posErrorDiffDifff = 0;
        current=0;
      
        %circle calcs
        r=850;

        x=640;
        y=0;
        th = 0:pi/800:pi;
        xunit = r * cos(th) + x;
        yunit = r * sin(th) + y;
        xunit=xunit(201:601);
        yunit=yunit(201:601);
        plot(app.UIAxes,1,1)
        xline(app.UIAxes,0, '--','color','blue')
        framePos=app.UIFigure.Position;
        app.UIAxes.Position = [493 100 1280 500];
        
        trials = 1;
        epoch=1;
        while (epoch <=numEpochs)
        startPosition = Motor1.ActualPosition;
        c = clock;
        clockStart = c(4)*3600+c(5)*60+c(6);
        clockStartPrev = clockStart;
        posClockPrev = clockStart;

        
        while (clockStart < clockStartPrev + rest)
        c = clock;
        clockStart = c(4)*3600+c(5)*60+c(6);
        if(clockStart > clockStartPrev + 4)
            app.counter.Text='1';
        elseif (clockStart > clockStartPrev + 3)
            app.counter.Text='2';
        elseif (clockStart > clockStartPrev + 2)
            app.counter.Text='3';   

        elseif (clockStart > clockStartPrev)
            app.counter.Text='Ready..';   
        end


        posError = -(Motor1.ActualPosition - (startPosition));
        angDeviation = posError/6400*90;
        angDeviation = floor(angDeviation) + floor( (angDeviation-floor(angDeviation))/0.25) * 0.25;
        %app.AngledegEditField.Value=angDeviation;
        if angDeviation>50
            angDeviation=50;
        elseif angDeviation<-50
            angDeviation=-50;
        end        

        redY=-10*angDeviation+100;
        app.red.Position=[625,redY,30,30];
        pause(0.001)

        end
        
        app.counter.Text='';

        trial=1;
        while(trial<=numTrials)
        app.trialCounter.Text=['Trial # ' num2str(trials)];
        
        if ~isempty(app)%pause button functionality
        checkPauseStopStatus(app)
        end
        
        c = clock;
        clockStart = c(4)*3600+c(5)*60+c(6);
        clockStartPrev = clockStart;
        posClockPrev = clockStart;
        
        wristData=[];
        levelData=[];
        strength=randi(3);
        randDelay=randi(500);
        randDelay=randDelay/1000;
        
        while (clockStart < clockStartPrev + hold+randDelay)
            
            c = clock;
            clockStart = c(4)*3600+c(5)*60+c(6);
            posError = -(Motor1.ActualPosition - (startPosition+100));
            angDeviation = posError/6400*90;
            angDeviation = floor(angDeviation) + floor( (angDeviation-floor(angDeviation))/0.25) * 0.25;
            %app.AngledegEditField.Value=angDeviation;
            if angDeviation>50
                angDeviation=50;
            elseif angDeviation<-50
                angDeviation=-50;
            end
            blueX = xunit(4*50.25);
            blueY= yunit(4*50.25)-400;

            posError = Motor1.ActualPosition - startPosition;
            posErrorDiff = posError - posErrorPrev;
            posClockDiff = clockStart - posClockPrev;

            
            if (epoch == 1) && (trials <= 6)
                if trials == 1
                    if (clockStart > clockStartPrev+3)
                        current=0;
                        app.blue.Visible=true;
                        app.green.Visible=false;
                        app.yellow.Visible=false;
                        app.darkRed.Visible=false;
                    elseif (clockStart > clockStartPrev)
                        app.blue.Visible=false;
                        app.green.Visible=true;
                        app.yellow.Visible=false;
                        app.darkRed.Visible=false;       
                    end
                elseif trials == 2
                    if (clockStart > clockStartPrev+3)
                        current=0;
                        app.blue.Visible=true;
                        app.green.Visible=false;
                        app.yellow.Visible=false;
                        app.darkRed.Visible=false;
                    elseif (clockStart > clockStartPrev)
                        app.blue.Visible=false;
                        app.green.Visible=false;
                        app.yellow.Visible=true;
                        app.darkRed.Visible=false;       
                    end
                elseif trials == 3
                    if (clockStart > clockStartPrev+3)
                        current=0;
                        app.blue.Visible=true;
                        app.green.Visible=false;
                        app.yellow.Visible=false;
                        app.darkRed.Visible=false;
                    elseif (clockStart > clockStartPrev)
                        app.blue.Visible=false;
                        app.green.Visible=false;
                        app.yellow.Visible=false;
                        app.darkRed.Visible=true;
                    end
                elseif trials == 4
                    if (clockStart > clockStartPrev+3)
                        current=0;
                        app.blue.Visible=true;
                        app.green.Visible=false;
                        app.yellow.Visible=false;
                        app.darkRed.Visible=false;
                    elseif (clockStart > clockStartPrev)
                        app.blue.Visible=false;
                        app.green.Visible=true;
                        app.yellow.Visible=false;
                        app.darkRed.Visible=false;       
                    end
                elseif trials == 5
                    if (clockStart > clockStartPrev+3)
                        current=0;
                        app.blue.Visible=true;
                        app.green.Visible=false;
                        app.yellow.Visible=false;
                        app.darkRed.Visible=false;
                    elseif (clockStart > clockStartPrev)
                        app.blue.Visible=false;
                        app.green.Visible=false;
                        app.yellow.Visible=true;
                        app.darkRed.Visible=false;       
                    end
                elseif trials == 6
                    if (clockStart > clockStartPrev+3)
                        current=0;
                        app.blue.Visible=true;
                        app.green.Visible=false;
                        app.yellow.Visible=false;
                        app.darkRed.Visible=false;
                    elseif (clockStart > clockStartPrev)
                        app.blue.Visible=false;
                        app.green.Visible=false;
                        app.yellow.Visible=false;
                        app.darkRed.Visible=true;       
                    end
                end
                    
                
            else
                if (clockStart > clockStartPrev+3)
                    current=0;
                    app.blue.Visible=true;
                    app.green.Visible=false;
                    app.yellow.Visible=false;
                    app.darkRed.Visible=false;
                elseif (clockStart > clockStartPrev)
                    app.blue.Visible=false;
                    if strength==1
                    app.green.Visible=true;
                    app.yellow.Visible=false;
                    app.darkRed.Visible=false;                
                    current=-2000;
                    elseif strength==2
                    app.yellow.Visible=true;
                    app.green.Visible=false;
                    app.darkRed.Visible=false;
                    current=-3000;
                    elseif strength==3
                    app.darkRed.Visible=true;
                    app.green.Visible=false;
                    app.yellow.Visible=false;
                    current=-4000;
                    end
                end
            end
       
            if (current > currentMaxP)
               current = currentMaxP;
            elseif (current < currentMaxN)
               current = currentMaxN;
            end
            
            %electronic stop for safety
            if abs(angDeviation)>45
                current =0;
            end
            Motor1.MotionWithCurrent(current)
        
            posErrorPrev = posError;
            posErrorDiffPrev = posErrorDiff;
            posClockPrev = clockStart;
    
    
            levelData=[levelData current];
            
            redY=-10*angDeviation+100;
            app.red.Position=[625,redY,30,30];
            wristData=[wristData angDeviation];
            
            pause(0.001)

        end
        trial=trial+1;
        name=strcat('trial',num2str(trials));
        gripForce.(name) = wristData;
        level.(name) = levelData;
        trials = trials + 1;
        end
        epoch = epoch+1;
        app.TaskLabel.Visible=true;
        app.upArrow.Visible=true;
        app.TaskLabel.Text='To continue press ';
        waitfor(app.logic,'Enable','on')
        app.logic.Enable=false;
        app.TaskLabel.Visible=false;
        app.upArrow.Visible=false;
        
        end
        grip.gripForce=gripForce;
        grip.level=level;
        save('grip.mat','grip')
        app.trialCounter.Visible=false;
        app.StartButton.Visible=false;
        app.PauseButton.Visible=false;
        app.space.Visible=false;
        app.TaskLabel.Visible=false;
        app.descriptionLabel.Visible=false;
        app.NextButton.Visible=true;
        app.instructionLabel.Text='Congratulations, you have finished this part of the experiment! To continue, press ';
        app.rightArrow.Visible=true;
        app.UIAxes.Visible=false;
        app.red.Visible=false;
        app.blue.Visible=false;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            App_torque;
            app.delete;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            switch key
                case 'return'
                    StartButtonPushed(app, event);
                case 'rightarrow'
                    NextButtonPushed(app, event);
                case 'space'
                    if app.PauseButton.Value == false
                        app.PauseButton.Value = true;
                    elseif app.PauseButton.Value == true
                        app.PauseButton.Value = false;
                    end

                case 'uparrow'
                    app.logic.Enable=true;
            end

        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
        if app.PauseButton.Value
            app.PauseButton.Text = 'Resume (Press Space)';
        else
            app.PauseButton.Text = 'Pause (Press Space)';
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.descriptionLabel = uilabel(app.UIFigure);
            app.descriptionLabel.Text = 'Set your wrist to neutral position and press ';
            app.TaskLabel = uilabel(app.UIFigure);
            app.TaskLabel.Text = 'Grip force';
            app.instructionLabel = uilabel(app.UIFigure);
            app.instructionLabel.Text = 'Match the required level of grip force by positioning the red dot in respective dots';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.blue = uiimage(app.UIFigure);
            app.blue.ImageSource = 'blue dot.png';
            app.trialCounter = uilabel(app.UIFigure);
            app.trialCounter.Text = '';
            app.counter = uilabel(app.UIFigure);
            app.counter.Text = '';
            app.darkRed = uiimage(app.UIFigure);
            app.darkRed.ImageSource = 'dot_black.png';
            app.green = uiimage(app.UIFigure);
            app.green.ImageSource = 'green dot.png';
            app.yellow = uiimage(app.UIFigure);
            app.yellow.ImageSource = 'Yellow_icon.png';
            app.red = uiimage(app.UIFigure);
            app.red.ImageSource = 'red_dot.png';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Visible = 'off';
            app.NextButton.Text = 'Next';
            app.logic = uibutton(app.UIFigure, 'push');
            app.logic.Enable = 'off';
            app.logic.Visible = 'off';
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Visible = 'off';
            app.PauseButton.Text = 'Pause';
            app.enter = uiimage(app.UIFigure);
            app.enter.ImageSource = 'enter-key.png';
            app.rightArrow = uiimage(app.UIFigure);
            app.rightArrow.Visible = 'off';
            app.rightArrow.ImageSource = 'right arrow.png';
            app.upArrow = uiimage(app.UIFigure);
            app.upArrow.Visible = 'off';
            app.upArrow.ImageSource = 'up arrow.png';
            app.space = uiimage(app.UIFigure);
            app.space.Visible = 'off';
            app.space.ImageSource = 'spacebar-icon-25.jpg';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_grip

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end