classdef Old_App_HI5_FullAssisted_targettracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        StartButton             matlab.ui.control.Button
        AngledegEditFieldLabel  matlab.ui.control.Label
        AngledegEditField       matlab.ui.control.NumericEditField
        descriptionLabel        matlab.ui.control.Label
        TaskLabel               matlab.ui.control.Label
        instructionLabel        matlab.ui.control.Label
        blue                    matlab.ui.control.Image
        red                     matlab.ui.control.Image
        counter                 matlab.ui.control.Label
        trialCounter            matlab.ui.control.Label
        PauseButton             matlab.ui.control.StateButton
        NextButton              matlab.ui.control.Button
        StartPositionButton     matlab.ui.control.Button
        speed                   matlab.ui.control.Label
        TrainingButton          matlab.ui.control.Button
        StartTrainingButton     matlab.ui.control.Button
        logic                   matlab.ui.control.Button
        logic_2                 matlab.ui.control.Button
        enter                   matlab.ui.control.Image
        rightArrow              matlab.ui.control.Image
        tKey                    matlab.ui.control.Image
        space                   matlab.ui.control.Image
        logic_3Button           matlab.ui.control.StateButton
        ErrorTextAreaLabel      matlab.ui.control.Label
        ErrorTextArea           matlab.ui.control.TextArea
        UIAxes                  matlab.ui.control.UIAxes
    end


    methods (Access = public)
        
function checkPauseStopStatus(app,flag)
        if app.PauseButton.Value
            app.counter.Visible=true;
            app.counter.Text='Paused';
            
            waitfor(app.PauseButton,'Value',false)
            app.counter.Visible=true;
            app.counter.Text='Ready..'; 
            pause(2);
            app.counter.Text='3';
            pause(1);
            app.counter.Text='2';
            pause(1);
            app.counter.Text='1';
            pause(1);
            app.counter.Visible=false;
            app.counter.Text=' '; 
        end
    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
        %start with hiding the unnecessary text
        app.space.Visible=true;
        app.enter.Visible=false;
        app.trialCounter.Visible=true;
        app.TaskLabel.Visible=false;
        app.descriptionLabel.Visible=false;
        app.StartButton.Visible=false;
        app.PauseButton.Visible=true;
        app.NextButton.Visible=false;
        app.ErrorTextAreaLabel.Visible = false;
        app.logic.Enable=false;
        app.logic_2.Enable = false;
        
        %variables
        hi5Target_fullAssisted={};%cell aray for positional data
        hi5WristPos={};%cell aray for positional data
        hi5TargetPos={};
        hold = 10;
        rest = 5;
        numTrials=1;
        currentMaxP = 10000;
        currentMaxN = -10000;
        KW = 2*6400/90; %spring: 1 light, 2 med (best demo), 3 large, 4 heavy (limit)
        DW = 0*6400/90;
        filterLP_D = 0.7; %filter error velocity: 0.7, not very sensitive
        
        %initialize
        Motor1 = Epos4(0,0);
        Motor1.ClearErrorState;
        Motor1.DisableNode;
        Motor1.SetOperationMode( OperationModes.CurrentMode );
        Motor1.EnableNode;
        Motor1.ClearErrorState;
        
        startPosition = Motor1.ActualPosition;
        position_Error = 0;
        posErrorDiff =0;
        posError = 0;
        posErrorPrev = 0;
        posErrorDiff = 0;
        posErrorDiffPrev = 0;
        posErrorDiffDifff = 0;
      
        %circle calcs and display
        r=850;
        x=640;
        y=0;
        th = 0:pi/800:pi;
        xunit = r * cos(th) + x;
        yunit = r * sin(th) + y;
        xunit=xunit(201:601);
        yunit=yunit(201:601);
        plot(app.UIAxes,xunit,yunit, '--','color','blue');
        app.UIFigure.Position = [0 0 1280 960];
        framePos=app.UIFigure.Position;
        app.blue.Position=[framePos(1)/2,framePos(2)/2,40,40];
        app.UIAxes.Position = [10 10 1280 640];
        
        trials = 1;
        sinPos=0;
        
        while (trials <=numTrials)
        app.trialCounter.Text=['Trial # ' num2str(trials)];
        
        if ~isempty(app)%pause button functionality
        checkPauseStopStatus(app)
        end
        
        c = clock;
        clockStart = c(4)*3600+c(5)*60+c(6);
        clockInit = clockStart;
        clockStartPrev = clockStart;
        posClockPrev = clockStart;

        %this is while loop for 3,2,1,ready counter
        while (clockStart < clockStartPrev + rest)
        c = clock;
        clockStart = c(4)*3600+c(5)*60+c(6);
        if(clockStart > clockStartPrev + 4)
            app.counter.Text='1';
        elseif (clockStart > clockStartPrev + 3)
            app.counter.Text='2';
        elseif (clockStart > clockStartPrev + 2)
            app.counter.Text='3';   

        elseif (clockStart > clockStartPrev)
            app.counter.Text='Ready..';   
        end
        
        position_encoder = Motor1.ActualPosition;
        posError = -(position_encoder - (startPosition));
        angDeviation = posError/6400*90;
        angDeviation = floor(angDeviation) + floor( (angDeviation-floor(angDeviation))/0.25) * 0.25;
        %app.AngledegEditField.Value=angDeviation;
        
        %limit movement to +-50 degs
        if angDeviation>50
            angDeviation=50;
        elseif angDeviation<-50
            angDeviation=-50;
        end
        
        blueX = xunit(4*(50.25));
        blueY= yunit(4*(50.25))-400;
        app.blue.Position=[blueX,blueY,50,50];
        
        redX=xunit(4*(50.25-angDeviation));
        redY=yunit(4*(50.25-angDeviation))-400;
        app.red.Position=[redX+10,redY+10,30,30];
        pause(0.001)%for some reason this pause is needed for smootheness   
        end
        
        app.counter.Text='';
        if ~isempty(app) %pause button functionality
        checkPauseStopStatus(app)
        end
        
        %initiate stuff before the trial loop
        c = clock;
        clockStart = c(4)*3600+c(5)*60+c(6);
        clockStartPrev = clockStart;
        posClockPrev = clockStart;
        clockstamp = [];
        hi5Wrist = [];
        hi5Target = [];
        hi5WristData=[];
        hi5TargetData=[];
        ErrorList = [];
        
        while (clockStart < clockStartPrev + hold)
            app.ErrorTextArea.Visible = false;
            app.ErrorTextAreaLabel.Visible = false;
            c = clock;
            clockStart = c(4)*3600+c(5)*60+c(6);
            
            position_encoder = Motor1.ActualPosition;
            posError = -(position_encoder - (startPosition));
            angDeviation = posError/6400*90;
            angDeviation = floor(angDeviation) + floor( (angDeviation-floor(angDeviation))/0.25) * 0.25;
            %app.AngledegEditField.Value=angDeviation;
            
            if angDeviation>50
                angDeviation=50;
            elseif angDeviation<-50
                angDeviation=-50;
            end
            
            %%eqs below govern behaviour of the blue dot
            sinTime=clockStart-clockStartPrev;
            sinPos=2*18.51*(sin(sinTime*pi/(1.547))*sin(sinTime*pi/(2.875)));%18.51
            
            %control Hi5 - stiffness
            position_Error = (position_encoder-startPosition)*90/6400 - sinPos;
            posErrorDiffNew = position_Error - posErrorPrev;
            posErrorDiff = posErrorDiff*(filterLP_D) + posErrorDiffNew*(1-filterLP_D); %damper can cause small vibrations 
            posErrorPrev = position_Error;

            current = -1*(KW*position_Error + DW*posErrorDiff);  
            if (current > currentMaxP)
                disp('Wall Too Strong!')
            elseif (current < currentMaxN)
                disp('Wall Broke!')
            else
                Motor1.MotionWithCurrent(current);
            end
            
            sinPos = floor(sinPos) + floor( (sinPos-floor(sinPos))/0.25) * 0.25;

            blueX = xunit(4*(50.25+sinPos));
            blueY= yunit(4*(50.25+sinPos))-400;
            app.blue.Position=[blueX,blueY,50,50];
            hi5Target=[hi5Target sinPos];
            
            redX=xunit(4*(50.25-angDeviation));
            redY=yunit(4*(50.25-angDeviation))-400;
            app.red.Position=[redX+10,redY+10,30,30];
            clockstamp = [clockstamp (clockStart-clockInit)];
            hi5Wrist=[hi5Wrist angDeviation];
            
            
            %ScorePerTrail = 100*abs(2*(blueX*redX+blueY*redY)-redX^2-redY^2)/abs(blueX^2 + blueY^2);
            ErrorPerTrail = sqrt((blueX-redX)^2+(blueY-redY)^2);
            ErrorList = [ErrorList ErrorPerTrail];
            Error = mean(ErrorList);
            
            pause(0.001)

        end
        hi5WristData = [clockstamp; hi5Wrist];
        hi5TargetData = [clockstamp; hi5Target];
        Motor1.MotionWithCurrent(0);

        app.ErrorTextAreaLabel.Visible = true;
        app.ErrorTextArea.Visible = true;
        app.ErrorTextArea.Value = num2str(Error,'%.2f');
        
        %save values from this trial
        name=strcat('trial',num2str(trials));
        hi5WristPos.(name) = hi5WristData;
        hi5TargetPos.(name) = hi5TargetData;
        trials = trials + 1;
        end
        hi5Target_fullAssisted.hi5WristPos=hi5WristPos;
        hi5Target_fullAssisted.hi5TargetPos=hi5TargetPos;
        save('hi5Target_fullAssisted.mat','hi5Target_fullAssisted')
        app.trialCounter.Visible=false;
        app.StartButton.Visible=false;
        app.PauseButton.Visible=false;
        app.space.Visible=false;
        app.TaskLabel.Visible=false;
        app.descriptionLabel.Visible=false;
        app.NextButton.Visible=true;
        app.rightArrow.Visible=true;
        app.instructionLabel.Text='Congratulations, you have finished this part of the experiment! To continue, press ';
        app.logic.Enable=true;
        app.logic_2.Enable=true;
        app.trialCounter.Text=' ';
            

        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
        if app.PauseButton.Value
            app.PauseButton.Text = 'Resume (Press Space)';
        else
            app.PauseButton.Text = 'Pause (Press Space)';
        end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'return'
                    if app.logic.Enable
                        StartPositionButtonPushed(app, event);
%                     else
%                         StartButtonPushed(app, event);
                    end
                case 'rightarrow'
                    if app.logic_2.Enable
                        NextButtonPushed(app, event);
                    end
                case 'space'
                    if app.PauseButton.Value == false
                        app.PauseButton.Value = true;
                    elseif app.PauseButton.Value == true
                        app.PauseButton.Value = false;
                    end
                case 't'
                    if app.logic_3Button.Enable
                        StartTrainingButtonPushed(app, event);
                    end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'background line_e.png';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.AngledegEditFieldLabel = uilabel(app.UIFigure);
            app.AngledegEditFieldLabel.Visible = 'off';
            app.AngledegEditFieldLabel.Text = 'Angle[deg]';
            app.AngledegEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngledegEditField.Visible = 'off';
            app.descriptionLabel = uilabel(app.UIFigure);
            app.descriptionLabel.Text = 'Set your wrist to neutral position and press ';
            app.TaskLabel = uilabel(app.UIFigure);
            app.TaskLabel.Text = 'Follow the target';
            app.instructionLabel = uilabel(app.UIFigure);
            app.instructionLabel.Text = 'Try to follow the target position wihout tensing the forearm muscles too much';
            app.blue = uiimage(app.UIFigure);
            app.blue.ImageSource = 'blue dot.png';
            app.red = uiimage(app.UIFigure);
            app.red.ImageSource = 'red_dot.png';
            app.counter = uilabel(app.UIFigure);
            app.counter.Text = '';
            app.trialCounter = uilabel(app.UIFigure);
            app.trialCounter.Text = '';
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Visible = 'off';
            app.PauseButton.Text = 'Pause';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.Visible = 'off';
            app.NextButton.Text = 'Next';
            app.StartPositionButton = uibutton(app.UIFigure, 'push');
            app.StartPositionButton.Visible = 'off';
            app.StartPositionButton.Text = 'Start';
            app.speed = uilabel(app.UIFigure);
            app.speed.Text = '';
            app.TrainingButton = uibutton(app.UIFigure, 'push');
            app.TrainingButton.Visible = 'off';
            app.TrainingButton.Text = 'Training';
            app.StartTrainingButton = uibutton(app.UIFigure, 'push');
            app.StartTrainingButton.Visible = 'off';
            app.StartTrainingButton.Text = 'Training';
            app.logic = uibutton(app.UIFigure, 'push');
            app.logic.Enable = 'off';
            app.logic.Visible = 'off';
            app.logic_2 = uibutton(app.UIFigure, 'push');
            app.logic_2.Enable = 'off';
            app.logic_2.Visible = 'off';
            app.enter = uiimage(app.UIFigure);
            app.enter.ImageSource = 'enter-key.png';
            app.rightArrow = uiimage(app.UIFigure);
            app.rightArrow.Visible = 'off';
            app.rightArrow.ImageSource = 'right arrow.png';
            app.tKey = uiimage(app.UIFigure);
            app.tKey.Visible = 'off';
            app.tKey.ImageSource = 'T key.png';
            app.space = uiimage(app.UIFigure);
            app.space.Visible = 'off';
            app.space.ImageSource = 'spacebar-icon-25.jpg';
            app.logic_3Button = uibutton(app.UIFigure, 'state');
            app.logic_3Button.Enable = 'off';
            app.logic_3Button.Visible = 'off';
            app.logic_3Button.Text = 'logic_3';
            app.ErrorTextAreaLabel = uilabel(app.UIFigure);
            app.ErrorTextAreaLabel.Visible = 'off';
            app.ErrorTextAreaLabel.Text = 'Error:';
            app.ErrorTextArea = uitextarea(app.UIFigure);
            app.ErrorTextArea.Visible = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Old_App_HI5_FullAssisted_targettracking

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end